classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        velocityGauge          matlab.ui.control.SemicircularGauge
        velocityGaugeLabel     matlab.ui.control.Label
        StatusLabel            matlab.ui.control.Label
        PositionLabel          matlab.ui.control.Label
        accelerationKnob       matlab.ui.control.Knob
        accelerationKnobLabel  matlab.ui.control.Label
        AccelerationLabel      matlab.ui.control.Label
        VelocityLabel          matlab.ui.control.Label
        KITTLamp               matlab.ui.control.Lamp
        KITTLampLabel          matlab.ui.control.Label
        KITTSwitch             matlab.ui.control.Switch
        KITTSwitchLabel        matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RandTimer          % Timer object
        PlotLine           % Line object
    end
    

    methods (Access = private)
    
        function RandTimerFcn(app,~,~)
            
            %v = str2double(app.VelocityLabel.Text);
            global v
            global kitt
            global pos
            global s 
            global a 
            if (s.button == true)
                 %uncomment to inpout velocity instead of
                %acceleration
                kitt.acceleration = a;
                %kitt.velocity = v
            else
                kitt.velocity = 0;
                kitt.acceleration = 0;
            end
            kitt
            v = kitt.velocity;
            a = kitt.acceleration;
            xpos = kitt.positionx;
            crash = kitt.crash;
            
            
                        value = kitt.velocity;
                        app.velocityGauge.Value = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
            
            
                        value = kitt.acceleration;
            value=sprintf('Acceleration = %.2f',value);
            app.AccelerationLabel.Text = (value);
            
            
                                    value = kitt.positionx;
            value=sprintf('Position = %.2f',value);
            app.PositionLabel.Text = (value);
            
            value = kitt.crash;
            if (value == 1)
            app.StatusLabel.Text = ('Status = crash occured');
            else
                app.StatusLabel.Text = ('Status = OK');
            end


            %pos(end + 1) = ceil(xpos);
            
            plot(app.UIAxes,20,xpos,'bo')
            hold( app.UIAxes, 'on' )
            xlim(app.UIAxes,[-10,610])
            ylim(app.UIAxes,[-10,610])
       end        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %rectangle(app.UIAxes,'Position',[1,2,5,10],'FaceColor',[0.8 0.8 0.8],'EdgeColor','k',...
    %'LineWidth',3);
            %axis(app.UIAxes, 'off');
            
            waypoint_1_x = 20;
            waypoint_1_y = 100;
            waypoint_2_x = 20;
            waypoint_2_y = 200;
            
            
            global kitt
            kitt = car;
            global v;
            v = 0;
            global a;
            a = 0;
            kitt.velocity = v;
            kitt.acceleration = a;
            global pos;
            pos = [0];
            
            global ti;
            ti = 0;
            
                app.KITTLamp.Color = 'red';
                        % Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function
            
           patch(app.UIAxes,'vertices', [100, 50; 100, 200; 200, 150; 200 100], ...
          'faces', [1, 2, 3, 4], ...
          'FaceColor', 'b', ...
          'FaceAlpha', 0.5)
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,waypoint_1_x,waypoint_1_y,'r+')
            plot(app.UIAxes,waypoint_2_x,waypoint_2_y,'r+')
            plot(app.UIAxes,0,0,'rx')
            plot(app.UIAxes,0,600,'rx')
            plot(app.UIAxes,600,0,'rx')
            plot(app.UIAxes,600,600,'rx')
            
            
            start(app.RandTimer);
            
            global s
             s = onoff;		% create an object containing a single  switch
                s = onoff(true);	% create a switch, initially 'on'
                s.button = true;	% switch 'on'
                s.button = false;	% switch 'off'
                s;			% show status


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.RandTimer);
            delete(app)
        end

        % Callback function
        function velocityKnobValueChanged(app, event)
            value = app.velocityKnob.Value;
            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Value changed function: KITTSwitch
        function KITTSwitchValueChanged(app, event)
            value = app.KITTSwitch.Value;
            global s
            if (strcmpi(value,'On') == 1)
                s.button = true;
                app.KITTLamp.Color = 'green';
            elseif (strcmpi(value,'Off') == 1)
                s.button = false;
                app.KITTLamp.Color = 'red';
            end
        end

        % Callback function
        function velocityKnobValueChanging(app, event)
            value = event.Value;

            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Value changing function: accelerationKnob
        function accelerationKnobValueChanging(app, event)
            value = event.Value;
            global a
            a = value;
        end

        % Callback function
        function crashButtonPushed(app, event)
            global kitt
            kitt.crash = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Position of KITT')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.KITTSwitchLabel = uilabel(app.UIFigure);
            app.KITTSwitchLabel.Text = 'KITT';
            app.KITTSwitch = uiswitch(app.UIFigure, 'slider');
            app.KITTSwitch.ValueChangedFcn = createCallbackFcn(app, @KITTSwitchValueChanged, true);
            app.KITTLampLabel = uilabel(app.UIFigure);
            app.KITTLampLabel.Text = 'KITT';
            app.KITTLamp = uilamp(app.UIFigure);
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity = ';
            app.AccelerationLabel = uilabel(app.UIFigure);
            app.AccelerationLabel.Text = 'Acceleration = ';
            app.accelerationKnobLabel = uilabel(app.UIFigure);
            app.accelerationKnobLabel.Text = 'acceleration';
            app.accelerationKnob = uiknob(app.UIFigure, 'continuous');
            app.accelerationKnob.Limits = [-15 15];
            app.accelerationKnob.ValueChangingFcn = createCallbackFcn(app, @accelerationKnobValueChanging, true);
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position = ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status = ';
            app.velocityGaugeLabel = uilabel(app.UIFigure);
            app.velocityGaugeLabel.Text = 'velocity';
            app.velocityGauge = uigauge(app.UIFigure, 'semicircular');
            app.velocityGauge.Limits = [-50 50];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end